classdef EMG_project_FATIH_SON_2_DENEME_with_muco_sabah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TostopEditField              matlab.ui.control.NumericEditField
        TabGroup                     matlab.ui.container.TabGroup
        real_time                    matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
        StopandSaveButton            matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        OnOffLamp                    matlab.ui.control.Lamp
        OnOffLampLabel               matlab.ui.control.Label
        UIAxes1_2                    matlab.ui.control.UIAxes
        UIAxes1_1                    matlab.ui.control.UIAxes
        Band_passTab_2               matlab.ui.container.Tab
        StopandSaveButton_2          matlab.ui.control.Button
        FilterOrderEditField         matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel    matlab.ui.control.Label
        CutOff_Freq_2EditField       matlab.ui.control.NumericEditField
        CutOff_Freq_2EditFieldLabel  matlab.ui.control.Label
        CutOff_Freq_1EditField       matlab.ui.control.NumericEditField
        CutOff_Freq_1EditFieldLabel  matlab.ui.control.Label
        PlotButton_3                 matlab.ui.control.Button
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3_4                    matlab.ui.control.UIAxes
        UIAxes3_3                    matlab.ui.control.UIAxes
        UIAxes3_1                    matlab.ui.control.UIAxes
        Tab                          matlab.ui.container.Tab
    end

   
    properties (Access = private)
        
        pks
        locs
        widths
        proms
    end
    
    methods (Access = private)
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes1_1
        function UIAxes1_1ButtonDown(app, event)
            
        end

        % Button down function: UIAxes1_2
        function UIAxes1_2ButtonDown(app, event)
            
        end

        % Callback function
        function SwitchValueChanged(app, event)

        end

        % Callback function
        function PlotButton_2Pushed(app, event)

        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
                fs = 1200;
                windw = 1200;
                windw_2=300;


                n = app.CutOff_Freq_1EditField.Value;  %cut off frequency 1
                if app.CutOff_Freq_2EditField.Value>fs/2
                app.CutOff_Freq_2EditField.Value=fs/2;
                end
                n1 = app.CutOff_Freq_2EditField.Value; %cut off frequency 2
                order = app.FilterOrderEditField.Value;
                    
            
                wc1 = n/(fs/2); %Highpass cutoff freq
                wc2 = n1/(fs/2); %lowpass cutoff freq

                %notch filter
                w0=50/(fs/2);
                bw=w0/30;
                [b,a]=iirnotch(w0,bw);


                % Butterworth bandpass desing
                [A,B] = butter(order,[wc1 wc2],'bandpass'); %4th order filter between 70Hz and 150Hz  
                % butter_bandpass_signal = filter(A,B,ns); %filtered signal
                % plot(app.UIAxes3_1,t,butter_bandpass_signal)


                %FFT for Butterworth bandpass
                % F2 = abs(fft(butter_bandpass_signal,fs+1)); %Changing the minus elements to the positive element
                % Fs = F2(1:fs/2 + 1)/(fs/2); %deleting mirror image of FFT of ns
                % plot(app.UIAxes3_2,0:fs/2,Fs) 

                %Chebycehv Bandpass Filter
                [A1,B1] = cheby1(4,3,[wc1 wc2]); % 4th order with 3db peak to peak passpand ripple between 70Hz and 120Hz
                % cc = filter(A1,B1,ns); %filtered signal
                % plot(app.UIAxes3_3,t,cc)

                %FFT for Chebycehv Bandpass
                % F1 = abs(fft(cc,fs+1)); %Changing the minus elements to the positive element
                % Fs = F1(1:fs/2 + 1)/(fs/2); %deleting mirror image of FFT of ns
                % plot(app.UIAxes3_4,0:fs/2,Fs)

            for i=1:inf                          
                        sgnl = importdata('deneme.txt');
                                
                        N = numel(sgnl);
                        t = 1:N;

                        ns=filter(b,a,sgnl);

                        % Butterworth bandpass desing
                        butter_bandpass_signal = filter(A,B,sgnl); %filtered signal
                        plot(app.UIAxes3_1,t(1,end-windw:end),butter_bandpass_signal(end-windw:end,1)/255)
                        % ylim(app.UIAxes3_1, [-2 2]);

                        %FFT for Butterworth bandpass
                        F2 = abs(fft(butter_bandpass_signal(end-windw_2:end,1),fs+1)); %Changing the minus elements to the positive element
                        Fs = F2(1:fs/2 + 1)/(fs/2);
                   
                        plot(app.UIAxes3_2,0:fs/2,Fs)
                        drawnow
                        %Chebycehv Bandpass Filter
                        cc = filter(A1,B1,sgnl); %filtered signal
                        plot(app.UIAxes3_3,t(1,end-windw:end),cc(end-windw:end,1)/255)
                        
                        %FFT for Chebycehv Bandpass
                        F1 = abs(fft(cc(end-windw_2:end,1),fs+1)); %Changing the minus elements to the positive element
                        Fs = F1(1:fs/2 + 1)/(fs/2); %deleting mirror image of FFT of ns
                        plot(app.UIAxes3_4,0:fs/2,Fs)

                        if app.TostopEditField.Value ~= 0

                        %%% Create a table with the data and variable names
                        % T = table(sgnl);
                        %%% Write data to text file
                        %writetable(T, 'Kel_signal.txt')
                        break
                        end
            end


        end

        % Callback function
        function PlotButton_4Pushed(app, event)

        end

        % Callback function
        function PlotButtonPushed(app, event)

        end

        % Callback function
        function StarttherealtimeplottingButtonPushed(app, event)

        end

        % Callback function
        function StopandCleartheplotButtonPushed(app, event)

        end

        % Callback function
        function PlotsavedsignalButtonPushed(app, event)

        end

        % Callback function
        function StartStopButtonValueChanged(app, event)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            fs = 1000;
            fs_2 = 1000;
            windw = 1000;
            windw_3 = 1000;

            app.TostopEditField.Value=0;
            
            for i=1:inf                          
                        sgnl = importdata('deneme.txt');
                                
                        N = numel(sgnl);
                        t = 1:N;
                      
                        plot(app.UIAxes1_1,t(1,end-windw:(end-1)),sgnl(end-windw:(end-1),1)/255)
                        % ylim(app.UIAxes1_1, [-2 2]);
                       
                        
                        sgnl = sgnl(end-windw_3:end,1)-mean(sgnl(end-windw_3:(end-1),1));
                        F = abs(fft(sgnl,fs_2+1));
                        Fs = F(1:fs_2/2 + 1)/(fs_2/2); 
                      
                        plot(app.UIAxes1_2,0:fs_2/2,Fs)
                        % ylim(app.UIAxes1_2, [1 2]);
                        drawnow

                         % heartMean = sgnl - movmean(sgnl, 40);
                         % heartMean= heartMean.^3;
                         % heartMean(heartMean< 0) = 0;
                         % sgnl_normalize = [normalize(heartMean(1:500), 'Range') normalize(heartMean(501:1000), 'Range') normalize(heartMean(1001:1500), 'Range') normalize(heartMean(1501:2000), 'Range')];
                         % [~, peaks] = findpeaks(sgnl_normalize, 'MinPeakHeight', 0.35);
                         % 
                         % seconddiffernece = mean(diff(peaks));
                         % seconds = mean((seconddiffernece*8)/2000);     
                         % bpm = (60/seconds) + 1;
                         % bpmvalue = mean(round(mean(round(bpm))));
                         % app.Label.Text = num2str(bpmvalue, '%.2f');

                        %  % Find the peak frequency (heart rate)
                        %  [~, peakIndex] = max(Fs);
                        %  heartRate = (peakIndex-1) * (fs / length(Fs)) * 60; % Convert to beats per minute (BPM)
                        % 
                        %  % Update the heart rate display in the GUI
                        % app.Label.Text = num2str(heartRate, '%.2f');
                        % 
                        %  drawnow;


%                         close all 
%                         cla(app.UIAxes1_1)
%                         cla(app.UIAxes1_2)
                         app.OnOffLamp.Color = 'g';
%                         plot(app.UIAxes1_1,t,sgnl)
%                         plot(app.UIAxes1_2,0:fs/2,Fs)
                     if app.TostopEditField.Value ~= 0

                        %%% Create a table with the data and variable names
                        % T = table(sgnl);
                        %%% Write data to text file
                        %writetable(T, 'Kel_signal.txt')
                        break
                     end
            end

                %   while 1
                % heartMean = sgnl - movmean(sgnl, 40);
                % heartMean= heartMean.^3;
                % heartMean(heartMean< 0) = 0;
                % sgnl_normalize = [normalize(heartMean(1:500), 'Range') normalize(heartMean(501:1000), 'Range') normalize(heartMean(1001:1500), 'Range') normalize(heartMean(1501:2000), 'Range')];
                % [~, peaks] = findpeaks(sgnl_normalize, 'MinPeakHeight', 0.35);
                % 
                % seconddiffernece = mean(diff(peaks));
                % seconds = mean((seconddiffernece*8)/2000);     
                % bpm = (60/seconds) + 1;
                % bpmvalue = mean(round(mean(round(bpm))));
                % bpmvalue=10;
                %         disp(bpmvalue);
                %   end


% DIGER KOD BPM ICIN

            [pks,locs,w,p] = findpeaks(sig); %find R peak
 meanw = mean(w); %w is the width of all the peaks
 meanw = 4.7879;
 heartRate = mean(w)*60;
   %%%%%%%               
              
        end

        % Button pushed function: StopandSaveButton
        function StopandSaveButtonPushed(app, event)
                
                app.TostopEditField.Value= 1;
                app.OnOffLamp.Color = 'r';
                        fs = 820;
                        sgnl = importdata('deneme.txt');
                                
                        N = numel(sgnl);
                        t = 1:N;
                        sgnl = sgnl-mean(sgnl);
                        F = abs(fft(sgnl,fs+1));
                        Fs = F(1:fs/2 + 1)/(fs/2); 
                
                plot(app.UIAxes1_1,t,sgnl/255)
                plot(app.UIAxes1_2,0:fs/2,Fs)
                ylim(app.UIAxes1_2, [0 5]);
        end

        % Value changed function: TostopEditField
        function TostopEditFieldValueChanged(app, event)
            value = app.TostopEditField.Value;
        end

        % Callback function
        function PlottoseecontractingtimeButtonPushed(app, event)

        end

        % Value changed function: CutOff_Freq_1EditField
        function CutOff_Freq_1EditFieldValueChanged(app, event)
            value = app.CutOff_Freq_1EditField.Value;
            
        end

        % Value changed function: CutOff_Freq_2EditField
        function CutOff_Freq_2EditFieldValueChanged(app, event)
            value = app.CutOff_Freq_2EditField.Value;
            
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            
        end

        % Button pushed function: StopandSaveButton_2
        function StopandSaveButton_2Pushed(app, event)
            
                      app.TostopEditField.Value= 1;

            fs = 1500;
               
                n = app.CutOff_Freq_1EditField.Value;  %cut off frequency 1
                n1 = app.CutOff_Freq_2EditField.Value; %cut off frequency 2
                order = app.FilterOrderEditField.Value;

                wc1 = n/(fs/2); %Highpass cutoff freq
                wc2 = n1/(fs/2); %lowpass cutoff freq

                %notch filter
                w0=50/(fs/2);
                bw=w0/30;
                [b,a]=iirnotch(w0,bw);


                % Butterworth bandpass desing
                [A,B] = butter(order,[wc1 wc2],'bandpass'); %4th order filter between 70Hz and 150Hz  
                % butter_bandpass_signal = filter(A,B,ns); %filtered signal
                % plot(app.UIAxes3_1,t,butter_bandpass_signal)


                %Chebycehv Bandpass Filter
                [A1,B1] = cheby1(4,3,[wc1 wc2]); % 4th order with 3db peak to peak passpand ripple between 70Hz and 120Hz
                % cc = filter(A1,B1,ns); %filtered signal
                % plot(app.UIAxes3_3,t,cc)

                
            sgnl = importdata('deneme.txt');
                                
                        N = numel(sgnl);
                        t = 1:N;

                        ns=filter(b,a,sgnl);

                        % Butterworth bandpass desing
                        butter_bandpass_signal = filter(A,B,ns); %filtered signal
                        plot(app.UIAxes3_1,t,butter_bandpass_signal/255)
                    
                        %FFT for Butterworth bandpass
                        F2 = abs(fft(butter_bandpass_signal,fs+1)); %Changing the minus elements to the positive element
                        Fs = F2(1:fs/2 + 1)/(fs/2);
                   
                        plot(app.UIAxes3_2,0:fs/2,Fs)
                        
                        %Chebycehv Bandpass Filter
                        cc = filter(A1,B1,ns); %filtered signal
                        plot(app.UIAxes3_3,t,cc/255)
                        
                        %FFT for Chebycehv Bandpass
                        F1 = abs(fft(cc,fs+1)); %Changing the minus elements to the positive element
                        Fs = F1(1:fs/2 + 1)/(fs/2); %deleting mirror image of FFT of ns
                        plot(app.UIAxes3_4,0:fs/2,Fs)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.real_time = uitab(app.TabGroup);
            app.real_time.Title = 'Real_time';
            app.UIAxes1_1 = uiaxes(app.real_time);
            title(app.UIAxes1_1, 'Real Time Signal')
            xlabel(app.UIAxes1_1, 'Time(ms)')
            ylabel(app.UIAxes1_1, 'Voltage')
            zlabel(app.UIAxes1_1, 'Z')
            app.UIAxes1_1.ButtonDownFcn = createCallbackFcn(app, @UIAxes1_1ButtonDown, true);
            app.UIAxes1_2 = uiaxes(app.real_time);
            title(app.UIAxes1_2, 'FFT of The Signal ')
            xlabel(app.UIAxes1_2, 'Frequency(Hz)')
            ylabel(app.UIAxes1_2, 'Amplitude')
            zlabel(app.UIAxes1_2, 'Z')
            app.UIAxes1_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes1_2ButtonDown, true);
            app.OnOffLampLabel = uilabel(app.real_time);
            app.OnOffLampLabel.Text = 'On/Off';
            app.OnOffLamp = uilamp(app.real_time);
            app.StartButton = uibutton(app.real_time, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopandSaveButton = uibutton(app.real_time, 'push');
            app.StopandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @StopandSaveButtonPushed, true);
            app.StopandSaveButton.Text = 'Stop and Save ';
            app.Label = uilabel(app.real_time);
            app.Band_passTab_2 = uitab(app.TabGroup);
            app.Band_passTab_2.Title = 'Band_pass';
            app.UIAxes3_1 = uiaxes(app.Band_passTab_2);
            title(app.UIAxes3_1, 'Butterworth bandpass')
            xlabel(app.UIAxes3_1, 'time(s)')
            ylabel(app.UIAxes3_1, 'Voltage')
            zlabel(app.UIAxes3_1, 'Z')
            app.UIAxes3_3 = uiaxes(app.Band_passTab_2);
            title(app.UIAxes3_3, 'Chebychev Bandpass')
            xlabel(app.UIAxes3_3, 'time(s)')
            ylabel(app.UIAxes3_3, 'Voltage')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.Band_passTab_2);
            title(app.UIAxes3_4, 'FFT for Chebychev Bandpass')
            xlabel(app.UIAxes3_4, 'Frequency(Hz)')
            ylabel(app.UIAxes3_4, 'Voltage')
            zlabel(app.UIAxes3_4, 'Z')
            app.UIAxes3_2 = uiaxes(app.Band_passTab_2);
            title(app.UIAxes3_2, 'FFT for Butterworth bandpass')
            xlabel(app.UIAxes3_2, 'Frequency(Hz)')
            ylabel(app.UIAxes3_2, 'Voltage')
            zlabel(app.UIAxes3_2, 'Z')
            app.PlotButton_3 = uibutton(app.Band_passTab_2, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.CutOff_Freq_1EditFieldLabel = uilabel(app.Band_passTab_2);
            app.CutOff_Freq_1EditFieldLabel.Text = 'CutOff_Freq_1';
            app.CutOff_Freq_1EditField = uieditfield(app.Band_passTab_2, 'numeric');
            app.CutOff_Freq_1EditField.ValueChangedFcn = createCallbackFcn(app, @CutOff_Freq_1EditFieldValueChanged, true);
            app.CutOff_Freq_2EditFieldLabel = uilabel(app.Band_passTab_2);
            app.CutOff_Freq_2EditFieldLabel.Text = 'CutOff_Freq_2';
            app.CutOff_Freq_2EditField = uieditfield(app.Band_passTab_2, 'numeric');
            app.CutOff_Freq_2EditField.ValueChangedFcn = createCallbackFcn(app, @CutOff_Freq_2EditFieldValueChanged, true);
            app.FilterOrderEditFieldLabel = uilabel(app.Band_passTab_2);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.Band_passTab_2, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.StopandSaveButton_2 = uibutton(app.Band_passTab_2, 'push');
            app.StopandSaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopandSaveButton_2Pushed, true);
            app.StopandSaveButton_2.Text = 'Stop and Save';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.TostopEditField = uieditfield(app.UIFigure, 'numeric');
            app.TostopEditField.ValueChangedFcn = createCallbackFcn(app, @TostopEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG_project_FATIH_SON_2_DENEME_with_muco_sabah

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end